classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WedgesEditFieldLabel          matlab.ui.control.Label
        WedgesEditField               matlab.ui.control.EditField
        SubmitButton                  matlab.ui.control.Button
        ConstraintsEditFieldLabel     matlab.ui.control.Label
        ConstraintsEditField          matlab.ui.control.EditField
        StrategyEditFieldLabel        matlab.ui.control.Label
        StrategyEditField             matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        Lamp2                         matlab.ui.control.Lamp
        Lamp2_2                       matlab.ui.control.Lamp
        progressLabel                 matlab.ui.control.Label
        TotalAvalibleWedgesPanel      matlab.ui.container.Panel
        instructionLabel              matlab.ui.control.Label
        Lamp2_3                       matlab.ui.control.Lamp
        HistoricalDataEditFieldLabel  matlab.ui.control.Label
        HistoricalDataEditField       matlab.ui.control.EditField
        CurrentStateLabel             matlab.ui.control.Label
        StrategiesTypeBox             matlab.ui.control.ListBox
        StrategySelect                matlab.ui.control.CheckBox
        StrategiesListBox             matlab.ui.control.ListBox
        selectedStrategiesBox         matlab.ui.control.ListBox
        AddButton                     matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        StrategiesLabel               matlab.ui.control.Label
        SelectedStrategiesLabel       matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        EmissionsPlotTab              matlab.ui.container.Tab
        MainPlot                      matlab.ui.control.UIAxes
        MassofCO2PlotTab              matlab.ui.container.Tab
        SecPlot                       matlab.ui.control.UIAxes
        AverageWarmingPlotTab         matlab.ui.container.Tab
        TerPlot                       matlab.ui.control.UIAxes
        ConstraintsPanel              matlab.ui.container.Panel
    end

    
    properties (Access = public)       
        wedgeData = 0;
        constraintsData = 0;
        strategyData = 0;
        historicalData = 0;
        selectedWedges = 0;
        startYear = 2018;
        GtC_est = 0;
        CO2=0;
        lambda = .7;
    end
    
    methods (Access = private)
        
        function output = readInFile(app,filepath,index)
            file = fopen(filepath,'r');
            fileData = {};
            header = fgetl(file);
            if index == 1
                delim = ';';
            elseif index == 4
                delim = '	';
            else
                delim = ' ';
            end
            header_p = strsplit(header,delim);
            temp_output = cell(1,2*length(header_p));
            temp_output(1:2:end) = header_p;
            if index == 4
                output = struct('Year',[],'GtCyr',[]);
            else
                output = struct(temp_output{1:end});
                if index == 1
                    app.selectedWedges = output;
                    app.selectedWedges.startYear = [];
                end
            end
            fields = fieldnames(output);
            while ~feof(file)
                curr_line = fgetl(file);
                curr_line_p = strsplit(curr_line,delim);
                
                for i = 1:length(curr_line_p)
                    if isempty(output.(fields{i}))
                        output.(fields{i}) = curr_line_p(i);
                    else
                        output.(fields{i})(end+1) = curr_line_p(i);
                    end
                end
            end                                    
        end
        
        function plotWarming(app)
            
            app.TerPlot.XLim = [2000,2068];
            years = 2000:1:2068;
            Temps = []
            F = @(x) 5.35 * log(x./278);
            T = @(x) app.lambda * F(x);
            for i = 1:length(years)
                Temps(end+1) = F(app.CO2(i));
            end
            plot(app.TerPlot,years,Temps);            
        end
        
        function plotWedges(app)
            cla(app.MainPlot);
            app.MainPlot.XLim = [1959,2068];
            plotHData(app);
            plotFlatline(app);
            for i = 1:length(app.selectedWedges.ID)
                years = [app.selectedWedges.startYear(i),2068];
                GtC_new = [app.GtC_est(app.selectedWedges.startYear(i)-2017),app.GtC_est(end)-i];
                GtC_old = [app.GtC_est(app.selectedWedges.startYear(i)-2017),app.GtC_est(end)-(i-1)]
                %GtC_new = [GtC_new(1),GtC_new(end)];
                plot(app.MainPlot,years,GtC_new);
                GtC_comb = [GtC_old,fliplr(GtC_new)];
                years_comb = [years,fliplr(years)]
                if strcmp(app.selectedWedges.Category{i},'Efficiency and Conservation')
                    fill(app.MainPlot,years_comb,GtC_comb,'y')
                elseif strcmp(app.selectedWedges.Category{i},'Fossil Fuel')
                    fill(app.MainPlot,years_comb,GtC_comb,'b')
                elseif strcmp(app.selectedWedges.Category{i},'Renewables and Biostorage')
                   fill(app.MainPlot,years_comb,GtC_comb,'g')
                else
                    fill(app.MainPlot,years_comb,GtC_comb,'r')
                end
            end                                                           
        end
        
    end   %%an end for methods.
    
    methods (Access = public)
        
        function writeWedges(app)
            file  = fopen('output.txt','w');
            header = 'ID;StrategyName;Sector;NumberUsed;TotalCost;Challenges\n';
            fwrite(file,header);
            currentID = NaN;
            duplicates = [];
            %Filters out duplicate wedges
            for i = 1:length(app.selectedWedges)
                if isequal(app.selectedWedges.ID{i},currentID)
                    curr = duplicates(find(duplicates(1:2:end) == currentID)+1);
                    curr = curr + 1;
                else
                    currentID = app.selectedWedges.ID{i};
                    duplicates(end+1) = [app.selectedWedges.ID{i},1];
                end
            end
            %Writes each wedge + data to file
            for i = 1:2:length(duplicates)
                line = app.selectedWedges.ID{duplicates(i)} + app.selectedWedges.StrategyName{i} + app.selectedWedges.Sector{i} + duplicates(i+1) + 2 *duplicates(i+1) + app.selectedWedges.Challenges{i};
                fwrite(line+'\n');
            end
            fclose(file);
            
        end
        
        function plotHData(app)
            %Imported Historical Data
            app.MainPlot.XLim = [1959,2068];
            years1= cellfun(@str2double,app.historicalData.Year)
            GtCyr =cellfun(@str2double,app.historicalData.GtCyr)
            %Estimation of future emissions
            years2_temp = [2018,2068];
            years2 = 2018:1:2068;
            app.GtC_est = [];
            t_prev =2017;
            %C_init = 800;
            E_init = 10;
            E= @(x) E_init * 2^((x-t_prev)./50);
            %C= @(x) C_init + (.5*E(x));
            for i = 1:length(years2)
                app.GtC_est(end+1) = E(years2(i));
                E_init = app.GtC_est(end);
                
            end
            years_cat = cat(2,years1,years2_temp);
            GtC_est_cat = cat(2,GtCyr,[app.GtC_est(1),app.GtC_est(end)]);
            plot(app.MainPlot,years_cat,GtC_est_cat);
            
            
        end                
        
        function plotFlatline(app)
            years = app.startYear:1:2068;
            init_value = app.GtC_est(app.startYear-2017);
            GtC_flatline = ones(size(years)).*init_value;
            plot(app.MainPlot,years,GtC_flatline);                        
        end
        
        function plotCO2(app)
            app.SecPlot.XLim = [2000,2068];
            years = 2000:1:2068;
            app.CO2 = []
            C_init = 800;                        
            %C02 for 2000-2017
            yearsFirst= cellfun(@str2double,app.historicalData.Year)
            GtCyrFirst =cellfun(@str2double,app.historicalData.GtCyr)
            index = find(yearsFirst == 2000);
            GtCyr_short = GtCyrFirst(index:end);
            for i = 1:18
                app.CO2(end+1) = C_init + (.5 * GtCyr_short(i));
                C_init = app.CO2(i);
            end
            
            %CO2 for 2018-2068
            for i = 1:51
                app.CO2(end+1) = C_init + (.5 * app.GtC_est(i));
                C_init = app.CO2(18+i);
            end            
            %Convert from GtC to ppm with conversion factor 2.1
            app.CO2 = app.CO2./(2.1);
            plot(app.SecPlot,years,app.CO2)            
        end        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x=2;
            app.StrategiesListBox.Items = {};
            app.selectedStrategiesBox.Items = {};
            app.Lamp.Color = [ 1 0 0];
            app.Lamp2.Color = [ 1 0 0];
            app.Lamp2_2.Color = [1 0 0 ];
            app.Lamp2_3.Color = [1 0 0 ];
            hold(app.MainPlot);
            
            app.MainPlot.Visible = 'off';
            app.SecPlot.Visible = 'off';
            app.TerPlot.Visible = 'off';
            app.AddButton.Visible = 'off';
            app.DeleteButton.Visible = 'off';
            app.StrategiesTypeBox.Visible = 'off';
            %app.TabGroup.Visible = 'off';
            app.ConstraintsPanel.Visible   = 'off';
            app.TotalAvalibleWedgesPanel.Visible = 'off';                     
        end

        % Value changed function: WedgesEditField
        function WedgesEditFieldValueChanged(app, event)
            wedge = app.WedgesEditField.Value;
            
        end

        % Value changed function: ConstraintsEditField
        function ConstraintsEditFieldValueChanged(app, event)
            constraints = app.ConstraintsEditField.Value;
            
        end

        % Value changed function: StrategyEditField
        function StrategyEditFieldValueChanged(app, event)
            strategy = app.StrategyEditField.Value;
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            fail_p=  1;
            try
                if app.StrategySelect.Value == 1
                    fpaths = {app.WedgesEditField.Value;app.ConstraintsEditField.Value;app.StrategyEditField.Value;app.HistoricalDataEditField.Value};
                    fail_p=  1;
                    app.wedgeData = app.readInFile(fpaths{1},1);
                    fail_p=  2;
                    app.constraintsData = app.readInFile(fpaths{2},2);
                    fail_p=  3;
                    app.strategyData = app.readInFile(fpaths{3},3);
                    fail_p = 4;
                    app.historicalData = app.readInFile(fpaths{4},4);
                    
                    app.Lamp.Color = [0 1 0];
                    app.Lamp2.Color = [0 1 0];
                    app.Lamp2_2.Color = [0 1 0];
                    app.Lamp2_3.Color = [0 1 0];
                else
                    fpaths = {app.WedgesEditField.Value;app.ConstraintsEditField.Value;app.HistoricalDataEditField.Value};
                    fail_p=  1;
                    app.wedgeData = app.readInFile(fpaths{1},1);
                    fail_p=  2;
                    app.constraintsData = app.readInFile(fpaths{2},2);
                    fail_p=  3;
                    app.historicalData = app.readInFile(fpaths{3},4);
                    fail_p=  4;
                    app.Lamp.Color = [0 1 0];
                    app.Lamp2.Color = [0 1 0];
                    app.Lamp2_2.Color = [0 1 0];
                    
                end                
                success = (msgbox('Success: All files read in','Success','modal'));  
                 app.AddButton.Visible = 'on';
                 app.DeleteButton.Visible = 'on';
                 app.StrategiesTypeBox.Visible = 'on';
                 app.TabGroup.Visible = 'on';
                 app.ConstraintsPanel.Visible   = 'on';
                 app.TotalAvalibleWedgesPanel.Visible = 'on';
                 app.MainPlot.Visible = 'on';
                 app.SecPlot.Visible = 'on';
                 app.TerPlot.Visible = 'on';
               
                CIDs = app.constraintsData.Constraints;
                CSect = app.constraintsData.Sector;               
                Cos = app.wedgeData.Cost;
                IDs = app.wedgeData.ID;
                Cats = app.wedgeData.Category;
                Strats = app.wedgeData.StrategyName
                
                CSectlabel = uilabel(app.ConstraintsPanel,'Text',CSect);
                CIDlabel =   uilabel(app.ConstraintsPanel,'Text',CIDs);                 
                 CIDlabel.Position = [10 5 10 40];
                 CSectlabel.Position = [20 5 90 40];
                    
                app.StrategiesListBox.Items = Strats;
                app.progressLabel.Text = '2) Modify Your Strategy';
                disp(app.wedgeData)
                delete(app.SubmitButton)
                %app.SubmitButton.Text = 'Go back'
                                              
                numIDs = numel(IDs);
                numCost = numel(Cos);
               IDlabel = uilabel(app.TotalAvalibleWedgesPanel,'Text',IDs);
               IDlabel_h = uilabel(app.TotalAvalibleWedgesPanel, 'Text', 'IDs');
               Costlabel = uilabel(app.TotalAvalibleWedgesPanel, 'Text',Cos);
               Costlabel_h = uilabel(app.TotalAvalibleWedgesPanel, 'Text', 'Costs');                        
                 IDlabel.Position = [6 -160 8 25*numIDs];
                    IDlabel_h.Position(1:2) = [5 275];
                 Costlabel.Position = [30 -160 15 25*numCost];
                    Costlabel_h.Position(1:2) = [23 275]; 
               Stratslabel = uilabel(app.TotalAvalibleWedgesPanel, 'Text', Strats);
               Stratslabel_h = uilabel(app.TotalAvalibleWedgesPanel, 'Text','Strategy Name')
                 Stratslabel.Position = [60 -160 115 25*numel(Cats)];
                     Stratslabel_h.Position = [60 269 98 20];
                                
                
                delete(app.WedgesEditField)
                delete(app.WedgesEditFieldLabel)
                delete(app.ConstraintsEditField)
                delete(app.ConstraintsEditFieldLabel)
                delete(app.StrategyEditField)
                delete(app.StrategyEditFieldLabel)
                delete(app.HistoricalDataEditField)
                delete(app.HistoricalDataEditFieldLabel)
                delete(app.instructionLabel)
                delete(app.StrategySelect)
                delete(app.Lamp)
                delete(app.Lamp2)
                delete(app.Lamp2_2)
                delete(app.Lamp2_3)
                
                
                plotHData(app);
                plotFlatline(app);
                plotCO2(app);
                plotWarming(app);
                
            catch
                error = msgbox(sprintf('Invalid Filename: filepath %d',fail_p), 'Error','error');
            end                                                           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: StrategiesTypeBox
        function StrategiesTypeBoxValueChanged(app, event)
            value = app.StrategiesTypeBox.Value;
            Selections = {'Efficiency & Conservation','Fossil-Fuel Reductions','Nuclear Energy','Biostorage & Renewables'};
            app.selectedWedges = value;
            
        end

        % Value changed function: StrategySelect
        function StrategySelectValueChanged(app, event)
            checkboxBool = app.StrategySelect.Value;
            
        end

        % Value changed function: StrategiesListBox
        function StrategiesListBoxValueChanged(app, event)
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            selected = app.StrategiesListBox.Value
            s_index = find(strcmp(app.wedgeData.StrategyName,selected));
            %Default Start Year
            startYear = 2018;
            
            fields = fieldnames(app.selectedWedges)
            for i = 1:length(fields)-1
                app.selectedWedges.(fields{i}){end+1} = app.wedgeData.(fields{i}){s_index};
                                                                                
            end
            app.selectedWedges.startYear(end+1) = startYear;
            if ismember(selected,app.selectedStrategiesBox.Items)
                counter = 1;
                for i = 1:length(app.selectedStrategiesBox.Items)
                    if isequal(selected,app.selectedStrategiesBox.Items{i}(1:length(selected)))
                        counter = counter+1;
                    end
                end
                selected = cat(2,selected,num2str(counter));
            end
            app.selectedStrategiesBox.Items = cat(2,app.selectedStrategiesBox.Items,selected);
            
            
            plotWedges(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if ~isempty(app.selectedStrategiesBox.Value)
                selected = app.selectedStrategiesBox.Value;
                s_index = find(strcmp(app.selectedStrategiesBox.Items,selected));
                current_vals = get(app.selectedStrategiesBox,'Items');
                current_vals(s_index) = [];
                app.selectedStrategiesBox.Items = current_vals;
                fields = fieldnames(app.selectedWedges);
                if length(app.selectedWedges.ID) == 1
                    for i = 1:length(fields)-1
                        app.selectedWedges.(fields{i}) = [] ;
                    end
                    app.selectedWedges.startYear = [];
                    
                else
                    for i = 1:length(fields)-1
                        app.selectedWedges.(fields{i})(s_index) = [] ;
                    end
                    app.selectedWedges.startYear(s_index) = [];
                end
            plotWedges(app);
            end                       
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WedgesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesEditFieldLabel.Text = 'Wedges';
            app.WedgesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesEditField.ValueChangedFcn = createCallbackFcn(app, @WedgesEditFieldValueChanged, true);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ConstraintsEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsEditFieldLabel.Text = 'Constraints';
            app.ConstraintsEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsEditField.ValueChangedFcn = createCallbackFcn(app, @ConstraintsEditFieldValueChanged, true);
            app.StrategyEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyEditFieldLabel.Text = 'Strategy';
            app.StrategyEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyEditField.ValueChangedFcn = createCallbackFcn(app, @StrategyEditFieldValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp2_2 = uilamp(app.UIFigure);
            app.progressLabel = uilabel(app.UIFigure);
            app.progressLabel.Text = '1) Input Source files';
            app.TotalAvalibleWedgesPanel = uipanel(app.UIFigure);
            app.TotalAvalibleWedgesPanel.Title = 'Total Avalible Wedges';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '*Please Enter names of the 3 or (4) files ';
            app.Lamp2_3 = uilamp(app.UIFigure);
            app.HistoricalDataEditFieldLabel = uilabel(app.UIFigure);
            app.HistoricalDataEditFieldLabel.Text = 'Historical Data';
            app.HistoricalDataEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentStateLabel = uilabel(app.UIFigure);
            app.CurrentStateLabel.Text = 'Current State:';
            app.StrategiesTypeBox = uilistbox(app.UIFigure);
            app.StrategiesTypeBox.Items = {'Efficiency & Conservation', 'Fossil-Fuel Reductions', 'Nuclear Energy', 'Biostorage & Renewables'};
            app.StrategiesTypeBox.Multiselect = 'on';
            app.StrategiesTypeBox.ValueChangedFcn = createCallbackFcn(app, @StrategiesTypeBoxValueChanged, true);
            app.StrategiesTypeBox.Value = {'Efficiency & Conservation'};
            app.StrategySelect = uicheckbox(app.UIFigure);
            app.StrategySelect.ValueChangedFcn = createCallbackFcn(app, @StrategySelectValueChanged, true);
            app.StrategySelect.Text = '';
            app.StrategiesListBox = uilistbox(app.UIFigure);
            app.StrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @StrategiesListBoxValueChanged, true);
            app.selectedStrategiesBox = uilistbox(app.UIFigure);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add ->';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = '<- Delete ';
            app.StrategiesLabel = uilabel(app.UIFigure);
            app.StrategiesLabel.Text = 'Strategies';
            app.SelectedStrategiesLabel = uilabel(app.UIFigure);
            app.SelectedStrategiesLabel.Text = 'Selected Strategies';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EmissionsPlotTab = uitab(app.TabGroup);
            app.EmissionsPlotTab.Title = 'Emissions Plot';
            app.MainPlot = uiaxes(app.EmissionsPlotTab);
            title(app.MainPlot, 'Gigatons of Carbon per Year')
            xlabel(app.MainPlot, 'Years')
            ylabel(app.MainPlot, 'GtC/Yr')
            app.MassofCO2PlotTab = uitab(app.TabGroup);
            app.MassofCO2PlotTab.Title = 'Mass of CO2 Plot';
            app.SecPlot = uiaxes(app.MassofCO2PlotTab);
            title(app.SecPlot, 'Mass Of Outputted CO2')
            xlabel(app.SecPlot, 'Years')
            ylabel(app.SecPlot, 'GtC')
            app.AverageWarmingPlotTab = uitab(app.TabGroup);
            app.AverageWarmingPlotTab.Title = 'Average Warming Plot';
            app.TerPlot = uiaxes(app.AverageWarmingPlotTab);
            title(app.TerPlot, 'Average Warming Of Earth')
            xlabel(app.TerPlot, 'Years')
            ylabel(app.TerPlot, 'Temperature Rise (degrees)')
            app.ConstraintsPanel = uipanel(app.UIFigure);
            app.ConstraintsPanel.Title = 'Active Constraints';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end